classdef O2_ROBOTDESCRIPTION < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Intro_page                     matlab.ui.Figure
        TextAreaLabel                  matlab.ui.control.Label
        appTitle                       matlab.ui.control.TextArea
        update_no_DH                   matlab.ui.control.Button
        RobotParametersLabel           matlab.ui.control.Label
        TextArea2                      matlab.ui.control.TextArea
        TotalNumberofJointsinyourRobotEditFieldLabel  matlab.ui.control.Label
        n_Joints                       matlab.ui.control.NumericEditField
        TextArea2_2                    matlab.ui.control.TextArea
        IsRevolute                     matlab.ui.container.ButtonGroup
        RevoluteButton                 matlab.ui.control.RadioButton
        PrismaticButton                matlab.ui.control.RadioButton
        text_box                       matlab.ui.control.Label
        EntertheLengthoftheLinkatthejointEditFieldLabel  matlab.ui.control.Label
        link_length                    matlab.ui.control.NumericEditField
        JointNumberEditFieldLabel      matlab.ui.control.Label
        i_joint                        matlab.ui.control.NumericEditField
        z_axisEditFieldLabel           matlab.ui.control.Label
        z_axis                         matlab.ui.control.EditField
        TextArea2_3                    matlab.ui.control.TextArea
        TotalNumberofJointsinyourRobotEditFieldLabel_2  matlab.ui.control.Label
        n_Joints_dh                    matlab.ui.control.NumericEditField
        TextArea2_4                    matlab.ui.control.TextArea
        LinkNumberLabel                matlab.ui.control.Label
        i_joint_dh                     matlab.ui.control.NumericEditField
        TypeOfJointButtonGroup         matlab.ui.container.ButtonGroup
        is_prismatic                   matlab.ui.control.RadioButton
        is_revolute                    matlab.ui.control.RadioButton
        dDistancealongzaxisLabel       matlab.ui.control.Label
        d_2                            matlab.ui.control.NumericEditField
        LinkLengthaLabel               matlab.ui.control.Label
        a_2                            matlab.ui.control.NumericEditField
        alphaAngleaboutxaxisinDEGREESEditFieldLabel  matlab.ui.control.Label
        alpha_2                        matlab.ui.control.NumericEditField
        thetaangleaboutzaxisinDEGREESEditFieldLabel  matlab.ui.control.Label
        theta_2                        matlab.ui.control.NumericEditField
        update_dh                      matlab.ui.control.Button
        ContributorsAnikethLolamLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        g=[] % Gravity Vector
        Robot % Robot
        Links={} %Array of Links
        torqfun=[] % Description
        alpha=[]
        d=[]
        theta=[]
        a=[]
        prevZ
        prevX
        prevY
        z_i
        x_i
        y_i
    end
    
    methods (Access = private)
        
        function DH = fromParameters(app,n,z,l,j_type)
            for i=1:n
                if (i==1)
                    Z_i= z(:,i);
                    app.prevZ=[0;0;1];
                    app.prevY=[0;1;0];
                    app.prevX=[1;0;0];
                else
                    app.z_i=z(:,i);
                    commonNormal=cross(app.z_i,PrevZ);
                    if(sum(commonNormal) == 0)
                        if(app.z_i(3) ~= 0)
                            app.x_i = [1;0;0];
                        elseif(app.z_i(2) ~= 0)
                            app.x_i = [1;0;0];
                        elseif(app.z_i(1) ~= 0)
                            app.x_i = [0;0;1];
                        end
                    else
                    %X is defined along the common normal, with direction from
                    %joint i to i+1
                        app.x_i = commonNormal;
                    %multiply by the sign of the of the link to go from joint i to
                    %i+1
                    %x_i = x_i.*sign(linkDir(i));
                    end
                    
                    app.y_i=cross(app.z_i,app.x_i);
                    if sum(app.y_i)==0
                        app.y_i=app.prevY;
                    end
                    % getting the value of a
                    if j_type(1,i-1)=='p'
                        app.a(i-1,1)=0;
                    else
                        app.a(i-1,1)=l(1,i-1);
                    end
                    %finding the value of d
                    if j_type(1,i-1)=='p'
                        app.d(i-1,1)=0;
                    else
                        if(j_type(1,i)=='p')&&(z(i-1,1)~=app.z_i)
                            app.d(i-1,1)=l(1,i-1);
                        elseif(z(i-1)==app.z_i)&& i==n
                            app.d(i-1,1)=l(1,i-1);
                        else
                            app.d(i-1,1)=0;
                        end
                    end
                    
                    %to find alpha
                    if (app.z_i==z(i-1,1))
                        app.alpha(i-1,1)=0;
                    else
                        ca =(dot(app.z_i,app.prevZ))/(sqrt(sum(app.z_i.^2))*sqrt(sum(app.prevZ.^2)));
                        app.alpha(i-1,i)=acos(ca);
                        if ca==0 && (min(cross(app.z_i,z(i-1,1)))==0)
                            app.alpha(i-1,i)=-app.alpha(i-1,i);
                        end
                        if ca==-1 && app.x_i==-app.prevX
                            app.alpha(i-1,i)=-app.alpha(i-1,i);
                        end
                        
                    end
                    
                    %to find theta
                    app.theta(i-1,1)=0;
                    
                    
                    app.prevZ=app.z_i;
                    app.prevX=app.x_i;
                    app.prevY=app.y_i;
            
                end
            end
            DH=[app.alpha, app.a,app.d, app.theta];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run startup_rvc.m
        end

        % Button pushed function: update_no_DH
        function update_no_DHButtonPushed(app, event)
           n=app.n_Joints.Value;
           z=zeros(3,n);
           l=zeros(1,n);
           i=app.i_joint.Value;
           z_int=str2num(app.z_axis.Value);
           z(:,i)=[z_int]';
           l(1,i)=app.link_length.Value;
           
           if(app.RevoluteButton.Value)
             j_type(1,i)='r';
           else
               j_type(1,i)='p';
           end
           disp(n);
           disp(i);
           disp(size(z))
           disp(size(j_type));
           disp(l);
           if(i==n)
               DH=app.fromParameters(n,z,l,j_type);
               for j=1:n
                   if j_type(1,j)=='p'
                       app.Links(1,j)=Link('a',DH(j,2),'alpha',DH(j,1),'theta',DH(j,4));
                   else
                       app.Links(1,j)=Link('d', DH(j,3),'a',DH(j,2),'alpha',DH(j,1));
                   end
               end
               app.Robot=SerialLink(app.Links);
           end
        end

        % Button pushed function: update_dh
        function update_dhButtonPushed(app, event)
           n=app.n_Joints_dh.Value;
           i=app.i_joint_dh.Value;
           
           if(app.is_prismatic.Value)
                app.Links{i}=Link('a',app.a_2.Value,'alpha',app.alpha_2.Value*pi/180,'theta',app.theta_2.Value*pi/180);
           else
               app.Links{i}=Link('a',app.a_2.Value,'alpha',app.alpha_2.Value*pi/180,'d',app.d_2.Value);
           end
          
           if(i==n)
               for j=1:n
                   x(j)=app.Links{j};
               end
               app.Robot=SerialLink(x);
           end
           app.Robot.plot(zeros(1,n));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Intro_page = uifigure('Visible', 'off');
            app.Intro_page.Name = 'UI Figure';
            app.TextAreaLabel = uilabel(app.Intro_page);
            app.TextAreaLabel.Text = 'Text Area';
            app.appTitle = uitextarea(app.Intro_page);
            app.appTitle.Value = {'MATLAB  Robotics ToolBox'};
            app.update_no_DH = uibutton(app.Intro_page, 'push');
            app.update_no_DH.ButtonPushedFcn = createCallbackFcn(app, @update_no_DHButtonPushed, true);
            app.update_no_DH.Text = 'Update for this Joint';
            app.RobotParametersLabel = uilabel(app.Intro_page);
            app.RobotParametersLabel.Text = 'Robot Parameters';
            app.TextArea2 = uitextarea(app.Intro_page);
            app.TextArea2.Value = {'If you do not have the DH parameters of your robot, please follow the below instructions'};
            app.TotalNumberofJointsinyourRobotEditFieldLabel = uilabel(app.Intro_page);
            app.TotalNumberofJointsinyourRobotEditFieldLabel.Text = 'Total Number of Joints in your Robot';
            app.n_Joints = uieditfield(app.Intro_page, 'numeric');
            app.TextArea2_2 = uitextarea(app.Intro_page);
            app.TextArea2_2.Value = {'Let us now define the parameters of each of the joints'};
            app.IsRevolute = uibuttongroup(app.Intro_page);
            app.IsRevolute.Title = 'Type of Joint';
            app.RevoluteButton = uiradiobutton(app.IsRevolute);
            app.RevoluteButton.Text = 'Revolute';
            app.RevoluteButton.Value = true;
            app.PrismaticButton = uiradiobutton(app.IsRevolute);
            app.PrismaticButton.Text = 'Prismatic';
            app.text_box = uilabel(app.Intro_page);
            app.text_box.Text = {'Define the axis of rotation or translation of the joint'; 'w.r.t base frame as a row vector'};
            app.EntertheLengthoftheLinkatthejointEditFieldLabel = uilabel(app.Intro_page);
            app.EntertheLengthoftheLinkatthejointEditFieldLabel.Text = 'Enter the Length of the Link at the joint';
            app.link_length = uieditfield(app.Intro_page, 'numeric');
            app.JointNumberEditFieldLabel = uilabel(app.Intro_page);
            app.JointNumberEditFieldLabel.Text = 'Joint Number';
            app.i_joint = uieditfield(app.Intro_page, 'numeric');
            app.z_axisEditFieldLabel = uilabel(app.Intro_page);
            app.z_axisEditFieldLabel.Text = 'z_axis';
            app.z_axis = uieditfield(app.Intro_page, 'text');
            app.TextArea2_3 = uitextarea(app.Intro_page);
            app.TextArea2_3.Value = {'If you have the DH parameters of your robot, please follow the below instructions'};
            app.TotalNumberofJointsinyourRobotEditFieldLabel_2 = uilabel(app.Intro_page);
            app.TotalNumberofJointsinyourRobotEditFieldLabel_2.Text = 'Total Number of Joints in your Robot';
            app.n_Joints_dh = uieditfield(app.Intro_page, 'numeric');
            app.TextArea2_4 = uitextarea(app.Intro_page);
            app.TextArea2_4.Value = {'Let us now define the parameters of each of the joints'};
            app.LinkNumberLabel = uilabel(app.Intro_page);
            app.LinkNumberLabel.Text = 'Link Number';
            app.i_joint_dh = uieditfield(app.Intro_page, 'numeric');
            app.TypeOfJointButtonGroup = uibuttongroup(app.Intro_page);
            app.TypeOfJointButtonGroup.Title = 'Type Of Joint';
            app.is_prismatic = uiradiobutton(app.TypeOfJointButtonGroup);
            app.is_prismatic.Text = 'Prismatic';
            app.is_prismatic.Value = true;
            app.is_revolute = uiradiobutton(app.TypeOfJointButtonGroup);
            app.is_revolute.Text = 'Revolute';
            app.dDistancealongzaxisLabel = uilabel(app.Intro_page);
            app.dDistancealongzaxisLabel.Text = 'd (Distance along z axis)';
            app.d_2 = uieditfield(app.Intro_page, 'numeric');
            app.LinkLengthaLabel = uilabel(app.Intro_page);
            app.LinkLengthaLabel.Text = 'Link Length :a';
            app.a_2 = uieditfield(app.Intro_page, 'numeric');
            app.alphaAngleaboutxaxisinDEGREESEditFieldLabel = uilabel(app.Intro_page);
            app.alphaAngleaboutxaxisinDEGREESEditFieldLabel.Text = 'alpha (Angle about x axis) in DEGREES';
            app.alpha_2 = uieditfield(app.Intro_page, 'numeric');
            app.thetaangleaboutzaxisinDEGREESEditFieldLabel = uilabel(app.Intro_page);
            app.thetaangleaboutzaxisinDEGREESEditFieldLabel.Text = 'theta (angle about z axis) in DEGREES';
            app.theta_2 = uieditfield(app.Intro_page, 'numeric');
            app.update_dh = uibutton(app.Intro_page, 'push');
            app.update_dh.ButtonPushedFcn = createCallbackFcn(app, @update_dhButtonPushed, true);
            app.update_dh.Text = 'Update for this Link';
            app.ContributorsAnikethLolamLabel = uilabel(app.Intro_page);
            app.ContributorsAnikethLolamLabel.Text = {'Contributors'; 'Aniketh Lolam  1218037574 '; 'Anirudhudu Duddila 1218577360'; 'PuneethPoreddy 1217397649'; 'Tapas Kar 1219741692'};
            % Show the figure after all components are created
            app.Intro_page.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = O2_ROBOTDESCRIPTION

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Intro_page)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Intro_page)
        end
    end
end