classdef O3_ROBOTDYNAMICS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RobotdefinitionPanel       matlab.ui.container.Panel
        TextArea                   matlab.ui.control.TextArea
        NumberofLinksLabel         matlab.ui.control.Label
        n_joints                   matlab.ui.control.NumericEditField
        TypeOfJointButtonGroup     matlab.ui.container.ButtonGroup
        is_prismatic               matlab.ui.control.RadioButton
        is_revolute                matlab.ui.control.RadioButton
        LinkLabel                  matlab.ui.control.Label
        link_number                matlab.ui.control.NumericEditField
        dDistancealongzaxisLabel   matlab.ui.control.Label
        d                          matlab.ui.control.NumericEditField
        LinkLengthaLabel           matlab.ui.control.Label
        a                          matlab.ui.control.NumericEditField
        alphaAngleaboutxaxisinDEGREESEditFieldLabel  matlab.ui.control.Label
        alpha                      matlab.ui.control.NumericEditField
        thetaangleaboutzaxisinDEGREESEditFieldLabel  matlab.ui.control.Label
        theta                      matlab.ui.control.NumericEditField
        UpdateForThisLinkButton    matlab.ui.control.Button
        GearRatioEditFieldLabel    matlab.ui.control.Label
        G                          matlab.ui.control.NumericEditField
        MassMomentofInteriaofMotorLabel  matlab.ui.control.Label
        Jm                         matlab.ui.control.NumericEditField
        CGxEditFieldLabel          matlab.ui.control.Label
        cgx                        matlab.ui.control.NumericEditField
        CGyEditFieldLabel          matlab.ui.control.Label
        cgy                        matlab.ui.control.NumericEditField
        IxzEditFieldLabel          matlab.ui.control.Label
        Ixz                        matlab.ui.control.NumericEditField
        IxyEditFieldLabel          matlab.ui.control.Label
        Ixy                        matlab.ui.control.NumericEditField
        IxxEditFieldLabel          matlab.ui.control.Label
        Ixx                        matlab.ui.control.NumericEditField
        IyyEditFieldLabel          matlab.ui.control.Label
        Iyy                        matlab.ui.control.NumericEditField
        IyzEditField_2Label        matlab.ui.control.Label
        Iyz                        matlab.ui.control.NumericEditField
        IzzEditFieldLabel          matlab.ui.control.Label
        Izz                        matlab.ui.control.NumericEditField
        CGzEditFieldLabel          matlab.ui.control.Label
        cgz                        matlab.ui.control.NumericEditField
        LinkmassEditFieldLabel     matlab.ui.control.Label
        link_mass                  matlab.ui.control.NumericEditField
        zaxisvectorEditFieldLabel  matlab.ui.control.Label
        zaxisvectorEditField       matlab.ui.control.NumericEditField
        gxEditFieldLabel           matlab.ui.control.Label
        gx                         matlab.ui.control.NumericEditField
        gyEditFieldLabel           matlab.ui.control.Label
        gy                         matlab.ui.control.NumericEditField
        gzEditFieldLabel           matlab.ui.control.Label
        gz                         matlab.ui.control.NumericEditField
        TextArea_4                 matlab.ui.control.TextArea
        DynamicDefinationsPanel    matlab.ui.container.Panel
        DoyouwanttoseethemanipulatordynamicsequationsyornLabel  matlab.ui.control.Label
        eqn_or_not                 matlab.ui.control.EditField
        OkButton                   matlab.ui.control.Button
        InputthetorqueofthatjointasarowvectorfunctionoftimeLabel  matlab.ui.control.Label
        torque_input               matlab.ui.control.EditField
        torque_update              matlab.ui.control.Button
        EndTimeEditFieldLabel      matlab.ui.control.Label
        tf                         matlab.ui.control.NumericEditField
        run_simulation             matlab.ui.control.Button
        InitialjointpositionsEditFieldLabel  matlab.ui.control.Label
        q0                         matlab.ui.control.EditField
        InitialJointDerivativesEditFieldLabel  matlab.ui.control.Label
        qd0                        matlab.ui.control.EditField
        TextArea_5                 matlab.ui.control.TextArea
        TextArea_6                 matlab.ui.control.TextArea
        B_matrix                   matlab.ui.control.Table
        C_matrix                   matlab.ui.control.Table
        g_component                matlab.ui.control.Table
        BMatrixLabel               matlab.ui.control.Label
        CMatrixLabel               matlab.ui.control.Label
        GvectorLabel               matlab.ui.control.Label
        joint_angles               matlab.ui.control.UIAxes
        joint_derivatives          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        n
        g=[] % Gravity Vector
        Robot % Robot
        Links={} %Array of Links
        torqfun=[] % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run startup_rvc.m
        end

        % Button pushed function: UpdateForThisLinkButton
        function UpdateForThisLinkButtonPushed(app, event)
           n=app.n_joints.Value;
           i=app.link_number.Value;
           ml=app.link_mass.Value;
           rr=[app.cgx.Value app.cgy.Value app.cgz.Value];
           Il=[app.Ixx.Value,-app.Ixy.Value,-app.Ixz.Value; ...
              -app.Ixy.Value, app.Iyy.Value, -app.Ixz.Value;...
              -app.Ixy.Value, -app.Iyz.Value, app.Izz.Value];
          
           mmi=app.Jm.Value;
           kr=app.G.Value;
           
           if(app.is_prismatic.Value)
                app.Links{i}=Link('a',app.a.Value,'alpha',app.alpha.Value*pi/180,'theta',app.theta.Value*pi/180,...
                    'm', ml, 'r',rr, 'I',Il, 'Jm',mmi, 'G',kr);
           else
               app.Links{i}=Link('a',app.a.Value,'alpha',app.alpha.Value*pi/180,'d',app.d.Value,...
                                 'm', ml, 'r',rr, 'I',Il, 'Jm',mmi, 'G',kr);
           end
           
           if(i==n)
               app.g=[app.gx.Value, app.gy.Value app.gz.Value];
               for j=1:n
                   x(j)=app.Links{j};
               end
               app.Robot=SerialLink(x);
               app.Robot.gravity=app.g*9.8;
           end
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            if app.eqn_or_not.Value =='y'
                %tau=DynamicsEquation(app.Robot)
                n=app.Robot.n; %Number of joints
                %keyboard();
                
                syms q [1,n]
                syms qd [1,n] 
                
                %app.Robot.fast=0;
                
                [B,C,Grav]=DynamicsEquation(app.Robot);
                
                %keyboard();
                %B=app.Robot.inertia(q);
                %C=app.Robot.coriolis(q,qd);
                %G=app.R.gravjac(q);
                %Grav=app.Robot.gravload(q);
                app.B_matrix.Data=arrayfun(@char, B,'uniform',0);
                app.C_matrix.Data=arrayfun(@char, C,'uniform',0);
                app.g_component.Data=arrayfun(@char, Grav,'uniform',0);
               
            end
        end

        % Button pushed function: torque_update
        function torque_updateButtonPushed(app, event)
            temp=app.torque_input.Value;
            temp=inline(temp,'t');
            app.torqfun=@(Robot,t,q,qd) temp(t);
        end

        % Button pushed function: run_simulation
        function run_simulationButtonPushed(app, event)
            tf=app.tf.Value;
            
            q0=str2num(app.q0.Value);
            qd0=str2num(app.qd0.Value);
            conf=app.Robot.config;
            for i=1:app.n
                if conf(1,i)=='R'
                    q0(1,i)=q0(1,i)*pi/180;
                end
            end
            tq=str2num(app.torque_input.Value);
            [ti,q,qd]=app.Robot.fdyn(tf,app.torqfun,q0,qd0);
            for i=1:size(ti)
                qdd(i,:)= app.Robot.accel(q(i,:),qd(i,:),tq);
            end
            title(app.joint_angles,'q vs t');
            title(app.joint_derivatives,'qdot vs t');
            plot(app.joint_angles,ti,q)
            plot(app.joint_derivatives,ti,qd);
            hold(app.joint_angles);
            hold(app.joint_derivatives); 
            figure(1)
            plot(ti,qdd);
            hold;
            title('qdd vs t');
            xlabel('time in s');
            ylabel('acceleration');
            figure(2);
            app.Robot.plot(q);
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RobotdefinitionPanel = uipanel(app.UIFigure);
            app.RobotdefinitionPanel.Title = 'Robot definition';
            app.TextArea = uitextarea(app.RobotdefinitionPanel);
            app.TextArea.Value = {'Define the Robot here'};
            app.NumberofLinksLabel = uilabel(app.RobotdefinitionPanel);
            app.NumberofLinksLabel.Text = 'Number of Links';
            app.n_joints = uieditfield(app.RobotdefinitionPanel, 'numeric');
            app.TypeOfJointButtonGroup = uibuttongroup(app.RobotdefinitionPanel);
            app.TypeOfJointButtonGroup.Title = 'Type Of Joint';
            app.is_prismatic = uiradiobutton(app.TypeOfJointButtonGroup);
            app.is_prismatic.Text = 'Prismatic';
            app.is_prismatic.Value = true;
            app.is_revolute = uiradiobutton(app.TypeOfJointButtonGroup);
            app.is_revolute.Text = 'Revolute';
            app.LinkLabel = uilabel(app.RobotdefinitionPanel);
            app.LinkLabel.Text = '# Link';
            app.link_number = uieditfield(app.RobotdefinitionPanel, 'numeric');
            app.dDistancealongzaxisLabel = uilabel(app.RobotdefinitionPanel);
            app.dDistancealongzaxisLabel.Text = 'd (Distance along z axis)';
            app.d = uieditfield(app.RobotdefinitionPanel, 'numeric');
            app.LinkLengthaLabel = uilabel(app.RobotdefinitionPanel);
            app.LinkLengthaLabel.Text = 'Link Length :a';
            app.a = uieditfield(app.RobotdefinitionPanel, 'numeric');
            app.alphaAngleaboutxaxisinDEGREESEditFieldLabel = uilabel(app.RobotdefinitionPanel);
            app.alphaAngleaboutxaxisinDEGREESEditFieldLabel.Text = 'alpha (Angle about x axis) in DEGREES';
            app.alpha = uieditfield(app.RobotdefinitionPanel, 'numeric');
            app.thetaangleaboutzaxisinDEGREESEditFieldLabel = uilabel(app.RobotdefinitionPanel);
            app.thetaangleaboutzaxisinDEGREESEditFieldLabel.Text = 'theta (angle about z axis) in DEGREES';
            app.theta = uieditfield(app.RobotdefinitionPanel, 'numeric');
            app.UpdateForThisLinkButton = uibutton(app.RobotdefinitionPanel, 'push');
            app.UpdateForThisLinkButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateForThisLinkButtonPushed, true);
            app.UpdateForThisLinkButton.Text = 'Update For This Link';
            app.GearRatioEditFieldLabel = uilabel(app.RobotdefinitionPanel);
            app.GearRatioEditFieldLabel.Text = 'Gear Ratio';
            app.G = uieditfield(app.RobotdefinitionPanel, 'numeric');
            app.G.Value = 111;
            app.MassMomentofInteriaofMotorLabel = uilabel(app.RobotdefinitionPanel);
            app.MassMomentofInteriaofMotorLabel.Text = 'Mass Moment of Interia of Motor';
            app.Jm = uieditfield(app.RobotdefinitionPanel, 'numeric');
            app.CGxEditFieldLabel = uilabel(app.RobotdefinitionPanel);
            app.CGxEditFieldLabel.Text = 'CG x';
            app.cgx = uieditfield(app.RobotdefinitionPanel, 'numeric');
            app.CGyEditFieldLabel = uilabel(app.RobotdefinitionPanel);
            app.CGyEditFieldLabel.Text = 'CG y';
            app.cgy = uieditfield(app.RobotdefinitionPanel, 'numeric');
            app.IxzEditFieldLabel = uilabel(app.RobotdefinitionPanel);
            app.IxzEditFieldLabel.Text = 'Ixz';
            app.Ixz = uieditfield(app.RobotdefinitionPanel, 'numeric');
            app.IxyEditFieldLabel = uilabel(app.RobotdefinitionPanel);
            app.IxyEditFieldLabel.Text = 'Ixy';
            app.Ixy = uieditfield(app.RobotdefinitionPanel, 'numeric');
            app.IxxEditFieldLabel = uilabel(app.RobotdefinitionPanel);
            app.IxxEditFieldLabel.Text = 'Ixx';
            app.Ixx = uieditfield(app.RobotdefinitionPanel, 'numeric');
            app.Ixx.Value = 1;
            app.IyyEditFieldLabel = uilabel(app.RobotdefinitionPanel);
            app.IyyEditFieldLabel.Text = 'Iyy';
            app.Iyy = uieditfield(app.RobotdefinitionPanel, 'numeric');
            app.Iyy.Value = 1;
            app.IyzEditField_2Label = uilabel(app.RobotdefinitionPanel);
            app.IyzEditField_2Label.Text = 'Iyz';
            app.Iyz = uieditfield(app.RobotdefinitionPanel, 'numeric');
            app.IzzEditFieldLabel = uilabel(app.RobotdefinitionPanel);
            app.IzzEditFieldLabel.Text = 'Izz';
            app.Izz = uieditfield(app.RobotdefinitionPanel, 'numeric');
            app.Izz.Value = 1;
            app.CGzEditFieldLabel = uilabel(app.RobotdefinitionPanel);
            app.CGzEditFieldLabel.Text = 'CG z';
            app.cgz = uieditfield(app.RobotdefinitionPanel, 'numeric');
            app.LinkmassEditFieldLabel = uilabel(app.RobotdefinitionPanel);
            app.LinkmassEditFieldLabel.Text = 'Link mass';
            app.link_mass = uieditfield(app.RobotdefinitionPanel, 'numeric');
            app.zaxisvectorEditFieldLabel = uilabel(app.RobotdefinitionPanel);
            app.zaxisvectorEditFieldLabel.Text = 'z-axis vector';
            app.zaxisvectorEditField = uieditfield(app.RobotdefinitionPanel, 'numeric');
            app.gxEditFieldLabel = uilabel(app.RobotdefinitionPanel);
            app.gxEditFieldLabel.Text = {'gx'; ''};
            app.gx = uieditfield(app.RobotdefinitionPanel, 'numeric');
            app.gyEditFieldLabel = uilabel(app.RobotdefinitionPanel);
            app.gyEditFieldLabel.Text = 'gy';
            app.gy = uieditfield(app.RobotdefinitionPanel, 'numeric');
            app.gzEditFieldLabel = uilabel(app.RobotdefinitionPanel);
            app.gzEditFieldLabel.Text = 'gz';
            app.gz = uieditfield(app.RobotdefinitionPanel, 'numeric');
            app.TextArea_4 = uitextarea(app.RobotdefinitionPanel);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'gravity vector w r t the robot base frame'};
            app.DynamicDefinationsPanel = uipanel(app.UIFigure);
            app.DynamicDefinationsPanel.Title = 'Dynamic Definations';
            app.DoyouwanttoseethemanipulatordynamicsequationsyornLabel = uilabel(app.DynamicDefinationsPanel);
            app.DoyouwanttoseethemanipulatordynamicsequationsyornLabel.Text = 'Do you want to see the manipulator dynamics equations (y or n)?';
            app.eqn_or_not = uieditfield(app.DynamicDefinationsPanel, 'text');
            app.OkButton = uibutton(app.DynamicDefinationsPanel, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.InputthetorqueofthatjointasarowvectorfunctionoftimeLabel = uilabel(app.DynamicDefinationsPanel);
            app.InputthetorqueofthatjointasarowvectorfunctionoftimeLabel.Text = 'Input the torque of that joint as a row vector function of time ';
            app.torque_input = uieditfield(app.DynamicDefinationsPanel, 'text');
            app.torque_update = uibutton(app.DynamicDefinationsPanel, 'push');
            app.torque_update.ButtonPushedFcn = createCallbackFcn(app, @torque_updateButtonPushed, true);
            app.torque_update.Text = 'Update';
            app.EndTimeEditFieldLabel = uilabel(app.DynamicDefinationsPanel);
            app.EndTimeEditFieldLabel.Text = 'End Time';
            app.tf = uieditfield(app.DynamicDefinationsPanel, 'numeric');
            app.run_simulation = uibutton(app.DynamicDefinationsPanel, 'push');
            app.run_simulation.ButtonPushedFcn = createCallbackFcn(app, @run_simulationButtonPushed, true);
            app.run_simulation.Text = 'Run Simulation';
            app.InitialjointpositionsEditFieldLabel = uilabel(app.DynamicDefinationsPanel);
            app.InitialjointpositionsEditFieldLabel.Text = 'Initial joint positions';
            app.q0 = uieditfield(app.DynamicDefinationsPanel, 'text');
            app.InitialJointDerivativesEditFieldLabel = uilabel(app.DynamicDefinationsPanel);
            app.InitialJointDerivativesEditFieldLabel.Text = 'Initial Joint Derivatives';
            app.qd0 = uieditfield(app.DynamicDefinationsPanel, 'text');
            app.TextArea_5 = uitextarea(app.DynamicDefinationsPanel);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Value = {'Input the joint angles and derivatives like this: [q1 q2]. Input torques like [t1 t2]'};
            app.TextArea_6 = uitextarea(app.DynamicDefinationsPanel);
            app.TextArea_6.Editable = 'off';
            app.TextArea_6.Value = {'**Due to Space Constraints the symbolic Equations of Motion can be seen on the Consol.'};
            app.B_matrix = uitable(app.UIFigure);
            app.B_matrix.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.B_matrix.RowName = {};
            app.C_matrix = uitable(app.UIFigure);
            app.C_matrix.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.C_matrix.RowName = {};
            app.g_component = uitable(app.UIFigure);
            app.g_component.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.g_component.RowName = {};
            app.BMatrixLabel = uilabel(app.UIFigure);
            app.BMatrixLabel.Text = 'B Matrix';
            app.CMatrixLabel = uilabel(app.UIFigure);
            app.CMatrixLabel.Text = 'C Matrix';
            app.GvectorLabel = uilabel(app.UIFigure);
            app.GvectorLabel.Text = 'G vector';
            app.joint_angles = uiaxes(app.UIFigure);
            title(app.joint_angles, 'Title')
            xlabel(app.joint_angles, 'X')
            ylabel(app.joint_angles, 'Y')
            app.joint_derivatives = uiaxes(app.UIFigure);
            title(app.joint_derivatives, 'Title')
            xlabel(app.joint_derivatives, 'X')
            ylabel(app.joint_derivatives, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = O3_ROBOTDYNAMICS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end