classdef O4_ROBOTCONTROL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LinkParametersPanel           matlab.ui.container.Panel
        UPDATE                        matlab.ui.control.Button
        LinkmassEditFieldLabel        matlab.ui.control.Label
        link_mass                     matlab.ui.control.NumericEditField
        CGxEditFieldLabel             matlab.ui.control.Label
        cgx                           matlab.ui.control.NumericEditField
        CGyEditFieldLabel             matlab.ui.control.Label
        cgy                           matlab.ui.control.NumericEditField
        CGzEditFieldLabel             matlab.ui.control.Label
        cgz                           matlab.ui.control.NumericEditField
        IxxEditFieldLabel             matlab.ui.control.Label
        Ixx                           matlab.ui.control.NumericEditField
        IxyEditFieldLabel             matlab.ui.control.Label
        Ixy                           matlab.ui.control.NumericEditField
        IyyEditFieldLabel             matlab.ui.control.Label
        Iyy                           matlab.ui.control.NumericEditField
        IxzEditFieldLabel             matlab.ui.control.Label
        Ixz                           matlab.ui.control.NumericEditField
        IyzEditField_2Label           matlab.ui.control.Label
        Iyz                           matlab.ui.control.NumericEditField
        IzzEditFieldLabel             matlab.ui.control.Label
        Izz                           matlab.ui.control.NumericEditField
        MotorIntertiaLabel            matlab.ui.control.Label
        Jm                            matlab.ui.control.NumericEditField
        GearRatioEditFieldLabel       matlab.ui.control.Label
        G                             matlab.ui.control.NumericEditField
        aLabel                        matlab.ui.control.Label
        dh_a                          matlab.ui.control.NumericEditField
        gxLabel                       matlab.ui.control.Label
        gx                            matlab.ui.control.NumericEditField
        gyLabel                       matlab.ui.control.Label
        gy                            matlab.ui.control.NumericEditField
        gzLabel                       matlab.ui.control.Label
        gz                            matlab.ui.control.NumericEditField
        NumberofJointsLabel           matlab.ui.control.Label
        n_joints                      matlab.ui.control.NumericEditField
        alphaLabel                    matlab.ui.control.Label
        dh_alpha                      matlab.ui.control.NumericEditField
        dLabel                        matlab.ui.control.Label
        dh_d                          matlab.ui.control.NumericEditField
        thetaLabel                    matlab.ui.control.Label
        dh_theta                      matlab.ui.control.NumericEditField
        LinkLabel                     matlab.ui.control.Label
        link_num                      matlab.ui.control.NumericEditField
        TextArea                      matlab.ui.control.TextArea
        TypeOfJointButtonGroup        matlab.ui.container.ButtonGroup
        is_prismatic                  matlab.ui.control.RadioButton
        is_revolute                   matlab.ui.control.RadioButton
        ControlVariablesPanel         matlab.ui.container.Panel
        qEditFieldLabel               matlab.ui.control.Label
        q0                            matlab.ui.control.EditField
        InitialValuesLabel            matlab.ui.control.Label
        qdEditFieldLabel              matlab.ui.control.Label
        qd0                           matlab.ui.control.EditField
        Entervaluesforqandqdasvectorsq1q2q3andforrevoluteLabel  matlab.ui.control.Label
        OpSpace                       matlab.ui.container.ButtonGroup
        isTwoD                        matlab.ui.control.RadioButton
        isThreeD                      matlab.ui.control.RadioButton
        PDControlButton               matlab.ui.control.Button
        InvDynamicsControlButton      matlab.ui.control.Button
        DoneButton                    matlab.ui.control.Button
        SelectyourcontrolmethodLabel  matlab.ui.control.Label
        StartPoseEditFieldLabel       matlab.ui.control.Label
        StartPose                     matlab.ui.control.EditField
        EndPoseEditFieldLabel         matlab.ui.control.Label
        EndPose                       matlab.ui.control.EditField
    end

    
    properties (Access = public)
        n
        Links={}
        Robot
        g=[]
        qo=[]
        q0dot=[]
        waypoints=[]
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UPDATE
        function UPDATEButtonPushed(app, event)
            app.n=app.n_joints.Value;
            i=app.link_num.Value;
            m=app.link_mass.Value;
            r=[app.cgx.Value app.cgy.Value app.cgz.Value];
            I=[app.Ixx.Value,-app.Ixy.Value,-app.Ixz.Value; ...
              -app.Ixy.Value, app.Iyy.Value, -app.Ixz.Value;...
              -app.Ixy.Value, -app.Iyz.Value, app.Izz.Value];
            mmi=app.Jm.Value;
            kr=app.G.Value;
            
            if(app.is_prismatic.Value)
                app.Links{i}=Link('a',app.dh_a.Value,'alpha',app.dh_alpha.Value*pi/180,'theta',app.dh_theta.Value*pi/180,...
                    'm', m, 'r',r, 'I',I, 'Jm',mmi, 'G',kr);
            else
               app.Links{i}=Link('a',app.dh_a.Value,'alpha',app.dh_alpha.Value*pi/180,'d',app.dh_d.Value,...
                                 'm', m, 'r',r, 'I',I, 'Jm',mmi, 'G',kr);
            end
           
            if(i==app.n)
               app.g=[app.gx.Value, app.gy.Value app.gz.Value];
               for j=1:app.n
                   x(j)=app.Links{j};
               end
               app.Robot=SerialLink(x);
               app.Robot.gravity=app.g*9.8;
            end 
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.qo=str2num(app.q0.Value);
            app.q0dot=str2num(app.qd0.Value);
            startpose=str2num(app.StartPose.Value);
            goalpose=str2num(app.EndPose.Value);

            app.waypoints=[startpose',goalpose'];
                    
            assignin('base', 'robotObj',app.Robot);
            assignin('base','qinit', app.qo);
            assignin('base','q0dot', app.q0dot);
            assignin('base','waypntr', app.waypoints);
            
        end

        % Button pushed function: PDControlButton
        function PDControlButtonPushed(app, event)
            sim('PD_Control_Gravity_Compensation.slx')
            set_param('PD_Control_Gravity_Compensation/Robot Manipulator',...
                'q0',app.qo','q0dot',app.q0dot');
            set_param('PD_Control_Gravity_Compensation/Trajectory',...
                'wayptr',app.waypoints);
            %run Init.m
%             sim('PD_Control_Gravity_Compensation.slx')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LinkParametersPanel = uipanel(app.UIFigure);
            app.LinkParametersPanel.Title = 'Link Parameters';
            app.UPDATE = uibutton(app.LinkParametersPanel, 'push');
            app.UPDATE.ButtonPushedFcn = createCallbackFcn(app, @UPDATEButtonPushed, true);
            app.UPDATE.Text = 'Update';
            app.LinkmassEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.LinkmassEditFieldLabel.Text = 'Link mass';
            app.link_mass = uieditfield(app.LinkParametersPanel, 'numeric');
            app.CGxEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.CGxEditFieldLabel.Text = 'CG x';
            app.cgx = uieditfield(app.LinkParametersPanel, 'numeric');
            app.CGyEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.CGyEditFieldLabel.Text = 'CG y';
            app.cgy = uieditfield(app.LinkParametersPanel, 'numeric');
            app.CGzEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.CGzEditFieldLabel.Text = 'CG z';
            app.cgz = uieditfield(app.LinkParametersPanel, 'numeric');
            app.IxxEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.IxxEditFieldLabel.Text = 'Ixx';
            app.Ixx = uieditfield(app.LinkParametersPanel, 'numeric');
            app.IxyEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.IxyEditFieldLabel.Text = 'Ixy';
            app.Ixy = uieditfield(app.LinkParametersPanel, 'numeric');
            app.IyyEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.IyyEditFieldLabel.Text = 'Iyy';
            app.Iyy = uieditfield(app.LinkParametersPanel, 'numeric');
            app.IxzEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.IxzEditFieldLabel.Text = 'Ixz';
            app.Ixz = uieditfield(app.LinkParametersPanel, 'numeric');
            app.IyzEditField_2Label = uilabel(app.LinkParametersPanel);
            app.IyzEditField_2Label.Text = 'Iyz';
            app.Iyz = uieditfield(app.LinkParametersPanel, 'numeric');
            app.IzzEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.IzzEditFieldLabel.Text = 'Izz';
            app.Izz = uieditfield(app.LinkParametersPanel, 'numeric');
            app.MotorIntertiaLabel = uilabel(app.LinkParametersPanel);
            app.MotorIntertiaLabel.Text = 'Motor Intertia';
            app.Jm = uieditfield(app.LinkParametersPanel, 'numeric');
            app.GearRatioEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.GearRatioEditFieldLabel.Text = 'Gear Ratio';
            app.G = uieditfield(app.LinkParametersPanel, 'numeric');
            app.aLabel = uilabel(app.LinkParametersPanel);
            app.aLabel.Text = ' a';
            app.dh_a = uieditfield(app.LinkParametersPanel, 'numeric');
            app.gxLabel = uilabel(app.LinkParametersPanel);
            app.gxLabel.Text = 'gx';
            app.gx = uieditfield(app.LinkParametersPanel, 'numeric');
            app.gyLabel = uilabel(app.LinkParametersPanel);
            app.gyLabel.Text = 'gy';
            app.gy = uieditfield(app.LinkParametersPanel, 'numeric');
            app.gzLabel = uilabel(app.LinkParametersPanel);
            app.gzLabel.Text = 'gz';
            app.gz = uieditfield(app.LinkParametersPanel, 'numeric');
            app.NumberofJointsLabel = uilabel(app.LinkParametersPanel);
            app.NumberofJointsLabel.Text = 'Number of Joints';
            app.n_joints = uieditfield(app.LinkParametersPanel, 'numeric');
            app.alphaLabel = uilabel(app.LinkParametersPanel);
            app.alphaLabel.Text = ' alpha';
            app.dh_alpha = uieditfield(app.LinkParametersPanel, 'numeric');
            app.dLabel = uilabel(app.LinkParametersPanel);
            app.dLabel.Text = 'd';
            app.dh_d = uieditfield(app.LinkParametersPanel, 'numeric');
            app.thetaLabel = uilabel(app.LinkParametersPanel);
            app.thetaLabel.Text = 'theta';
            app.dh_theta = uieditfield(app.LinkParametersPanel, 'numeric');
            app.LinkLabel = uilabel(app.LinkParametersPanel);
            app.LinkLabel.Text = 'Link #';
            app.link_num = uieditfield(app.LinkParametersPanel, 'numeric');
            app.TextArea = uitextarea(app.LinkParametersPanel);
            app.TextArea.Value = {'**If the joint is prismatic, enter d as 0 and if revolute, enter theta as 0'};
            app.TypeOfJointButtonGroup = uibuttongroup(app.LinkParametersPanel);
            app.TypeOfJointButtonGroup.Title = 'Type Of Joint';
            app.is_prismatic = uiradiobutton(app.TypeOfJointButtonGroup);
            app.is_prismatic.Text = 'Prismatic';
            app.is_prismatic.Value = true;
            app.is_revolute = uiradiobutton(app.TypeOfJointButtonGroup);
            app.is_revolute.Text = 'Revolute';
            app.ControlVariablesPanel = uipanel(app.UIFigure);
            app.ControlVariablesPanel.Title = 'Control Variables';
            app.qEditFieldLabel = uilabel(app.ControlVariablesPanel);
            app.qEditFieldLabel.Text = 'q';
            app.q0 = uieditfield(app.ControlVariablesPanel, 'text');
            app.InitialValuesLabel = uilabel(app.ControlVariablesPanel);
            app.InitialValuesLabel.Text = 'Initial Values';
            app.qdEditFieldLabel = uilabel(app.ControlVariablesPanel);
            app.qdEditFieldLabel.Text = 'qd';
            app.qd0 = uieditfield(app.ControlVariablesPanel, 'text');
            app.Entervaluesforqandqdasvectorsq1q2q3andforrevoluteLabel = uilabel(app.ControlVariablesPanel);
            app.Entervaluesforqandqdasvectorsq1q2q3andforrevoluteLabel.Text = {'**Enter values for q and qd as vectors: [q1;q2;q3] '; 'For revolute joints specify q in RADIANs. and qd rad/sec '};
            app.OpSpace = uibuttongroup(app.ControlVariablesPanel);
            app.OpSpace.Title = 'Operational Space dimension';
            app.isTwoD = uiradiobutton(app.OpSpace);
            app.isTwoD.Text = '2D';
            app.isTwoD.Value = true;
            app.isThreeD = uiradiobutton(app.OpSpace);
            app.isThreeD.Text = '3D';
            app.PDControlButton = uibutton(app.ControlVariablesPanel, 'push');
            app.PDControlButton.ButtonPushedFcn = createCallbackFcn(app, @PDControlButtonPushed, true);
            app.PDControlButton.Text = 'PD Control';
            app.InvDynamicsControlButton = uibutton(app.ControlVariablesPanel, 'push');
            app.InvDynamicsControlButton.Text = 'Inv Dynamics Control';
            app.DoneButton = uibutton(app.ControlVariablesPanel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.SelectyourcontrolmethodLabel = uilabel(app.ControlVariablesPanel);
            app.SelectyourcontrolmethodLabel.Text = 'Select your control method';
            app.StartPoseEditFieldLabel = uilabel(app.ControlVariablesPanel);
            app.StartPoseEditFieldLabel.Text = 'Start Pose';
            app.StartPose = uieditfield(app.ControlVariablesPanel, 'text');
            app.EndPoseEditFieldLabel = uilabel(app.ControlVariablesPanel);
            app.EndPoseEditFieldLabel.Text = 'End Pose';
            app.EndPose = uieditfield(app.ControlVariablesPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = O4_ROBOTCONTROL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end