classdef O5_HOMOGENEOUSTRANSFORMATION < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TransformButton             matlab.ui.control.Button
        NumberofrotationsEditFieldLabel  matlab.ui.control.Label
        NumberofrotationsEditField  matlab.ui.control.NumericEditField
        RotationnoEditFieldLabel    matlab.ui.control.Label
        RotationnoEditField         matlab.ui.control.NumericEditField
        XpositionEditFieldLabel     matlab.ui.control.Label
        XpositionEditField          matlab.ui.control.NumericEditField
        YpositionEditFieldLabel     matlab.ui.control.Label
        YpositionEditField          matlab.ui.control.NumericEditField
        ZpositionEditFieldLabel     matlab.ui.control.Label
        ZpositionEditField          matlab.ui.control.NumericEditField
        UpdateButton                matlab.ui.control.Button
        AxisEditFieldLabel          matlab.ui.control.Label
        AxisEditField               matlab.ui.control.EditField
        AngleEditFieldLabel         matlab.ui.control.Label
        AngleEditField              matlab.ui.control.NumericEditField
        AATable                     matlab.ui.control.Table
        HomogeneousTransformLabel   matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       T={} % Description
       A={} % Description   
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TransformButton
        function TransformButtonPushed(app, event)
           No_Rotatinal_Transformation=str2double(inputdlg('Number of Rotation')); 
syms x y z 
theta=ones(No_Rotatinal_Transformation,1);e=eye(4);r=eye(3);P0=zeros(1,3);T=eye(4);
for i=1:No_Rotatinal_Transformation   
theta(i)=str2double(inputdlg(['Angle of rotation theta in degree ',num2str(i),' ' ]));
W(i)=(inputdlg( [num2str(i), ' Axis of Rotation'] ));
endeffectorX=str2double(inputdlg('Input End Effector POsition X '));
endeffectorY=str2double(inputdlg('Input End Effector POsition Y '));
endeffectorZ=str2double(inputdlg('Input End Effector POsition Z '));

if W(i)==x
    
    w1=1;
    w2=0;
    w3=0;
end
if W(i)==y
    w1=0;        
    w2=1;
    w3=0;
end
if W(i)==z
    w1=0;
    w2=0;
    w3=1;
end

st=sind(theta(i));
ct=cosd(theta(i));
vt=(1-ct);

E1=[w1^2*vt+ct        w1*w2*vt-w3*st    w1*w3*vt+w2*st endeffectorX;
   w1*w2*vt+w3*st    w2^2*vt+ct        w2*w3*vt-w1*st endeffectorY;
   w1*w3*vt-w2*st    w2*w3*vt+w1*st    w3^2*vt+ct endeffectorZ;
   0 0 0 1];
eval(sprintf('E%d = E1;',i));
e=E1;
eval(sprintf('E%d',i))

P1=e(1:3,4);
eval(sprintf('X%d = P1;',i));
p=P1;
eval(sprintf('X%d',i))

r1=e(1:3,1:3);
eval(sprintf('R%d = r1;',i));
r=r*r1;
eval(sprintf('R%d',i))
end 
grid on
plotTransforms(P0,[1 0 0 0])
hold on
x1=[0 endeffectorY];
y1=[0 endeffectorY];
z1=[0 endeffectorZ];
plot3(x1, y1, z1,'O--') 
% plotTransforms([EEPX EEPY EEPZ],q)
xlabel('X-axis Red')
ylabel('Y-axis Green')
zlabel('Z-axis Blue')
P=[endeffectorX endeffectorY endeffectorZ];
Pf=r*P';
plot3([0 Pf(1)], [0 Pf(2)],[0 Pf(3)],'b--')
legend('Vector A', 'Transform Vector A')

app.TMTable.Data=Pf;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)


i=app.RotationnoEditField.Value;
t={app.AngleEditField.Value};
a={app.AxisEditField.Value};
app.T(i)= [t];
app.A(i)= (a);
x=[app.A' app.T'];
app.AATable.Data=x;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TransformButton = uibutton(app.UIFigure, 'push');
            app.TransformButton.ButtonPushedFcn = createCallbackFcn(app, @TransformButtonPushed, true);
            app.TransformButton.Text = 'Transform ';
            app.NumberofrotationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofrotationsEditFieldLabel.Text = 'Number of rotations ';
            app.NumberofrotationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotationnoEditFieldLabel = uilabel(app.UIFigure);
            app.RotationnoEditFieldLabel.Text = 'Rotation no.';
            app.RotationnoEditField = uieditfield(app.UIFigure, 'numeric');
            app.XpositionEditFieldLabel = uilabel(app.UIFigure);
            app.XpositionEditFieldLabel.Text = 'X-position';
            app.XpositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.YpositionEditFieldLabel = uilabel(app.UIFigure);
            app.YpositionEditFieldLabel.Text = 'Y-position';
            app.YpositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZpositionEditFieldLabel = uilabel(app.UIFigure);
            app.ZpositionEditFieldLabel.Text = 'Z-position';
            app.ZpositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.AxisEditFieldLabel = uilabel(app.UIFigure);
            app.AxisEditFieldLabel.Text = 'Axis';
            app.AxisEditField = uieditfield(app.UIFigure, 'text');
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle ';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.AATable = uitable(app.UIFigure);
            app.AATable.ColumnName = {'Axis'; 'Angle'};
            app.AATable.RowName = {};
            app.HomogeneousTransformLabel = uilabel(app.UIFigure);
            app.HomogeneousTransformLabel.Text = 'Homogeneous Transform';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = O5_HOMOGENEOUSTRANSFORMATION

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end