classdef control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LinkParametersPanel           matlab.ui.container.Panel
        UPDATE                        matlab.ui.control.Button
        LinkmassEditFieldLabel        matlab.ui.control.Label
        link_mass                     matlab.ui.control.NumericEditField
        CGxEditFieldLabel             matlab.ui.control.Label
        cgx                           matlab.ui.control.NumericEditField
        CGyEditFieldLabel             matlab.ui.control.Label
        cgy                           matlab.ui.control.NumericEditField
        CGzEditFieldLabel             matlab.ui.control.Label
        cgz                           matlab.ui.control.NumericEditField
        IxxEditFieldLabel             matlab.ui.control.Label
        Ixx                           matlab.ui.control.NumericEditField
        IxyEditFieldLabel             matlab.ui.control.Label
        Ixy                           matlab.ui.control.NumericEditField
        IyyEditFieldLabel             matlab.ui.control.Label
        Iyy                           matlab.ui.control.NumericEditField
        IxzEditFieldLabel             matlab.ui.control.Label
        Ixz                           matlab.ui.control.NumericEditField
        IyzEditField_2Label           matlab.ui.control.Label
        Iyz                           matlab.ui.control.NumericEditField
        IzzEditFieldLabel             matlab.ui.control.Label
        Izz                           matlab.ui.control.NumericEditField
        MotorIntertiaLabel            matlab.ui.control.Label
        Jm                            matlab.ui.control.NumericEditField
        GearRatioEditFieldLabel       matlab.ui.control.Label
        G                             matlab.ui.control.NumericEditField
        aLabel                        matlab.ui.control.Label
        dh_a                          matlab.ui.control.NumericEditField
        gxLabel                       matlab.ui.control.Label
        gx                            matlab.ui.control.NumericEditField
        gyLabel                       matlab.ui.control.Label
        gy                            matlab.ui.control.NumericEditField
        gzLabel                       matlab.ui.control.Label
        gz                            matlab.ui.control.NumericEditField
        NumberofJointsLabel           matlab.ui.control.Label
        n_joints                      matlab.ui.control.NumericEditField
        alphaLabel                    matlab.ui.control.Label
        dh_alpha                      matlab.ui.control.NumericEditField
        dLabel                        matlab.ui.control.Label
        dh_d                          matlab.ui.control.NumericEditField
        thetaLabel                    matlab.ui.control.Label
        dh_theta                      matlab.ui.control.NumericEditField
        LinkLabel                     matlab.ui.control.Label
        link_num                      matlab.ui.control.NumericEditField
        TextArea                      matlab.ui.control.TextArea
        TypeOfJointButtonGroup        matlab.ui.container.ButtonGroup
        is_prismatic                  matlab.ui.control.RadioButton
        is_revolute                   matlab.ui.control.RadioButton
        ControlVariablesPanel         matlab.ui.container.Panel
        qEditFieldLabel               matlab.ui.control.Label
        q0                            matlab.ui.control.EditField
        InitialValuesLabel            matlab.ui.control.Label
        qdEditFieldLabel              matlab.ui.control.Label
        qd0                           matlab.ui.control.EditField
        Entervaluesforqandqdasvectorsq1q2q3andforrevoluteLabel  matlab.ui.control.Label
        OpSpace                       matlab.ui.container.ButtonGroup
        isTwoD                        matlab.ui.control.RadioButton
        isThreeD                      matlab.ui.control.RadioButton
        PDControlButton               matlab.ui.control.Button
        InvDynamicsControlButton      matlab.ui.control.Button
        DoneButton                    matlab.ui.control.Button
        SelectyourcontrolmethodLabel  matlab.ui.control.Label
        EulerAnglesZYZformatLabel     matlab.ui.control.Label
        EulerAngles                   matlab.ui.control.EditField
        DesiredPoseLabel              matlab.ui.control.Label
        DesiredPose                   matlab.ui.control.EditField
        xd_xe                         matlab.ui.control.UIAxes
        lin_for                       matlab.ui.control.UIAxes
        ang_for                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        n
        Links={}
        Robot
        g=[]
        qo=[]
        q0dot=[]
        waypoints=[]
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run startup_rvc.m
        end

        % Button pushed function: UPDATE
        function UPDATEButtonPushed(app, event)
            app.n=app.n_joints.Value;
            i=app.link_num.Value;
            m=app.link_mass.Value;
            r=[app.cgx.Value app.cgy.Value app.cgz.Value];
            I=[app.Ixx.Value,-app.Ixy.Value,-app.Ixz.Value; ...
              -app.Ixy.Value, app.Iyy.Value, -app.Iyz.Value;...
              -app.Ixz.Value, -app.Iyz.Value, app.Izz.Value];
            mmi=app.Jm.Value;
            kr=app.G.Value;
            
            if(app.is_prismatic.Value)
                app.Links{i}=Link('a',app.dh_a.Value,'alpha',app.dh_alpha.Value*pi/180,'theta',app.dh_theta.Value*pi/180,...
                    'm', m, 'r',r, 'I',I, 'Jm',mmi, 'G',kr);
            else
               app.Links{i}=Link('a',app.dh_a.Value,'alpha',app.dh_alpha.Value*pi/180,'d',app.dh_d.Value,...
                                 'm', m, 'r',r, 'I',I, 'Jm',mmi, 'G',kr);
            end
           
            if(i==app.n)
               app.g=[app.gx.Value, app.gy.Value app.gz.Value];
               for j=1:app.n
                   x(j)=app.Links{j};
               end
               app.Robot=SerialLink(x);
               app.Robot.gravity=app.g*9.8;
            end 
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.qo=str2num(app.q0.Value);
            app.q0dot=str2num(app.qd0.Value);
            Euler_A=str2num(app.EulerAngles.Value)
            goalpose=str2num(app.DesiredPose.Value)

            app.waypoints=[Euler_A',goalpose'];
%             app.waypoints=[goalpose];        
            assignin('base', 'robotObj',app.Robot);
            assignin('base','qinit', app.qo);
            assignin('base','q0dot', app.q0dot);
            assignin('base','waypntr', app.waypoints);
            
        end

        % Button pushed function: PDControlButton
        function PDControlButtonPushed(app, event)
%             set_param('PD_Control_Gravity_Compensation/Input',...
%                 'waypntr', app.waypoints');
%             set_param('PD_Control_Gravity_Compensation/Robot Manipulator',...
%                 'q',app.qo','qdot',app.q0dot');
%             set_param('PD_Control_Gravity_Compensation/Trajectory',...
%                 'wayptr',app.waypoints);
%             %run Init.m
%             sim('PD_Control_Gravity_Compensation.slx')

%start from here

%x_d, q0,qd0 are given
% x_e(1)=app.R.fkine(q(1));
            app.qo=str2num(app.q0.Value);
            app.q0dot=str2num(app.qd0.Value);
            Euler_A=str2num(app.EulerAngles.Value);
            goalpose=str2num(app.DesiredPose.Value);
            q__0=app.qo
            q__0dot=app.q0dot
            x_d=(goalpose')
            T_xe=app.Robot.fkine(app.qo)
            x_e = T_xe(1:3,4)
            R_e = T_xe(1:3,1:3)
            R_d = eul2r(Euler_A')
            R_de = R_d'*R_e
            x_de = R_d'*(x_d'-x_e)
            T_de = [R_de x_de; zeros(1,3) 1]
            Euler_de = (tr2eul(T_de))'
            x = [x_de; Euler_de]
            Ta_de = [eye(2,2), zeros(2,4);zeros(4,2) T_de]
            J = app.Robot.jacob0(app.qo)
            J_ad = inv(Ta_de)*[R_d', zeros(3); zeros(3), R_d']*J
            t=0;Kp=16750*eye(6); Kd=12750*eye(6); m=2;
            
            
%             for j=1:m            
% %               J=app.Robot.jacob0(app.qo(j));
%                 h_de=inv(Ta_de)'.*Kp*x;
%                 B=app.Robot.inertia(app.qo(j));
%                 C=app.Robot.coriolis(app.qo(j),app.q0dot(j));
%                 F=app.Robot.friction(app.qo(j));
%                 Grav=app.Robot.gravload(app.qo(j));
%                 u=Grav+J_ad'*(Kp*x-Kd*J_ad*app.q0dot);
%                 qdd=inv(B)*(u-C*app.q0dot-F*app.q0dot-Grav-(J)'*h_de);
%                 app.q0dot=inv(Ja)*x_e;
%                 app.qo(j+1)=app.qo(j)+app.q0dot*0.001;
%                 T_xe=app.Robot.fkine(app.qo(j+1));
%                 x_e = T_xe(1:3,4);
%                 R_e = T_xe(1:3,1:3);
%                 R_de = R_d'*R_e;
%                 x_de = R_d'*(x_d-x_e);
%                 T_de = [R_de x_de; zeros(1,3) 1];
%                 Euler_de = tr2eul(T_de);
%                 x = [x_de; Euler_de];
%                 Ta_de = [eye(3), zeros(3); zeros(3), T_de];
%                 J = app.Robot.jacob0(app.qo(j));
%                 J_ad = inv(Ta_de)*[R_d', zeros(3); zeros(3), R_d']*J;
%                 t=t+0.01;
%                 m=m+1;
%                 if x_d==x_e
%                     break
%                 end
%             end
%    
               t=0:1:4; %or whatever end time is specified.
               h_de=zeros(6,length(t));

            for j=1:length(t)                                                               
                q__0=app.qo
                q__0dot=app.q0dot
                size(q__0)
                size(q__0dot)
%               J=app.Robot.jacob0(app.qo);            %% 6byn
                h_de(:,j)=inv(Ta_de)'*Kp*x;            %% 6bylength(t)
                B=app.Robot.inertia(app.qo');           %% nby1
                C=app.Robot.coriolis(app.qo',app.q0dot');%% nby1  
                F=app.Robot.friction(app.qo);          %% nby1
                Grav=app.Robot.gravload(app.qo');       %% nby1
                u=Grav'+J_ad'*(Kp*x-Kd*J_ad*app.q0dot); %% nby1
                h_de(:,j)
                qdd=inv(B)*(u-C*app.q0dot-Grav'-(J')*h_de(:,j)); %% 3by1
                app.q0dot=app.q0dot+qdd.*0.001                               %% 3by1 
                app.qo=app.qo+app.q0dot.*0.001                              %% 3by1 
                T_xe=app.Robot.fkine(app.qo);                               %% 4by4
                x_e(:,j) = T_xe(1:3,4);                                     %% 3bylength(t) storing 3 elements of x_e in length(t) number of columns
                R_e = T_xe(1:3,1:3);                                        %% 3by3
                R_de = R_d'*R_e;                                            %% 3by3
                x_de(:,j) = R_d'*(x_d'-x_e(:,j));                            %% 3by1 i think we have to plot this vs x_e 
                T_de = [R_de x_de(:,j); zeros(1,3) 1];                      %% 4by4
                Euler_de = tr2eul(T_de);                                    %% 3by1
                x = [x_de(:,j); Euler_de'];                                  %% 6by1 error vector does not need to store
                Ta_de = [eye(2,2), zeros(2,4);zeros(4,2), T_de];                 %% 6by6
                J = app.Robot.jacobn(app.qo);                               %% 6byn actually comment it out. already written in the top 
                J_ad = inv(Ta_de)*[R_d', zeros(3); zeros(3), R_d']*J;       %% 6byn
            end


             title(app.xd_xe,'Desired v/s End-Effector')
             
%             title(app.joint_derivatives,'Joint Velocities')
%             title(app.joint_accelerations,'Joint Accelerations')
             hold(app.xd_xe);
             hold(app.lin_for);
             hold(app.ang_for);
%             hold(app.joint_derivatives);
             for j=1:length(t)
                 plot(app.xd_xe,x_e(:,j),x_de(:,j))
                 plot(app.lin_for,x_de(:,j),h_de(1:3,j))
                 
                 plot(app.ang_for,x_de(:,j),h_de(3:6,j))
                 
%                plot(app.joint_derivatives,t,qd(:,j));
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LinkParametersPanel = uipanel(app.UIFigure);
            app.LinkParametersPanel.Title = 'Link Parameters';
            app.UPDATE = uibutton(app.LinkParametersPanel, 'push');
            app.UPDATE.ButtonPushedFcn = createCallbackFcn(app, @UPDATEButtonPushed, true);
            app.UPDATE.Text = 'Update';
            app.LinkmassEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.LinkmassEditFieldLabel.Text = 'Link mass';
            app.link_mass = uieditfield(app.LinkParametersPanel, 'numeric');
            app.CGxEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.CGxEditFieldLabel.Text = 'CG x';
            app.cgx = uieditfield(app.LinkParametersPanel, 'numeric');
            app.CGyEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.CGyEditFieldLabel.Text = 'CG y';
            app.cgy = uieditfield(app.LinkParametersPanel, 'numeric');
            app.CGzEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.CGzEditFieldLabel.Text = 'CG z';
            app.cgz = uieditfield(app.LinkParametersPanel, 'numeric');
            app.IxxEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.IxxEditFieldLabel.Text = 'Ixx';
            app.Ixx = uieditfield(app.LinkParametersPanel, 'numeric');
            app.IxyEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.IxyEditFieldLabel.Text = 'Ixy';
            app.Ixy = uieditfield(app.LinkParametersPanel, 'numeric');
            app.IyyEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.IyyEditFieldLabel.Text = 'Iyy';
            app.Iyy = uieditfield(app.LinkParametersPanel, 'numeric');
            app.IxzEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.IxzEditFieldLabel.Text = 'Ixz';
            app.Ixz = uieditfield(app.LinkParametersPanel, 'numeric');
            app.IyzEditField_2Label = uilabel(app.LinkParametersPanel);
            app.IyzEditField_2Label.Text = 'Iyz';
            app.Iyz = uieditfield(app.LinkParametersPanel, 'numeric');
            app.IzzEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.IzzEditFieldLabel.Text = 'Izz';
            app.Izz = uieditfield(app.LinkParametersPanel, 'numeric');
            app.MotorIntertiaLabel = uilabel(app.LinkParametersPanel);
            app.MotorIntertiaLabel.Text = 'Motor Intertia';
            app.Jm = uieditfield(app.LinkParametersPanel, 'numeric');
            app.GearRatioEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.GearRatioEditFieldLabel.Text = 'Gear Ratio';
            app.G = uieditfield(app.LinkParametersPanel, 'numeric');
            app.aLabel = uilabel(app.LinkParametersPanel);
            app.aLabel.Text = ' a';
            app.dh_a = uieditfield(app.LinkParametersPanel, 'numeric');
            app.gxLabel = uilabel(app.LinkParametersPanel);
            app.gxLabel.Text = 'gx';
            app.gx = uieditfield(app.LinkParametersPanel, 'numeric');
            app.gyLabel = uilabel(app.LinkParametersPanel);
            app.gyLabel.Text = 'gy';
            app.gy = uieditfield(app.LinkParametersPanel, 'numeric');
            app.gzLabel = uilabel(app.LinkParametersPanel);
            app.gzLabel.Text = 'gz';
            app.gz = uieditfield(app.LinkParametersPanel, 'numeric');
            app.NumberofJointsLabel = uilabel(app.LinkParametersPanel);
            app.NumberofJointsLabel.Text = 'Number of Joints';
            app.n_joints = uieditfield(app.LinkParametersPanel, 'numeric');
            app.alphaLabel = uilabel(app.LinkParametersPanel);
            app.alphaLabel.Text = ' alpha';
            app.dh_alpha = uieditfield(app.LinkParametersPanel, 'numeric');
            app.dLabel = uilabel(app.LinkParametersPanel);
            app.dLabel.Text = 'd';
            app.dh_d = uieditfield(app.LinkParametersPanel, 'numeric');
            app.thetaLabel = uilabel(app.LinkParametersPanel);
            app.thetaLabel.Text = 'theta';
            app.dh_theta = uieditfield(app.LinkParametersPanel, 'numeric');
            app.LinkLabel = uilabel(app.LinkParametersPanel);
            app.LinkLabel.Text = 'Link #';
            app.link_num = uieditfield(app.LinkParametersPanel, 'numeric');
            app.TextArea = uitextarea(app.LinkParametersPanel);
            app.TextArea.Value = {'**If the joint is prismatic, enter d as 0 and if revolute, enter theta as 0'};
            app.TypeOfJointButtonGroup = uibuttongroup(app.LinkParametersPanel);
            app.TypeOfJointButtonGroup.Title = 'Type Of Joint';
            app.is_prismatic = uiradiobutton(app.TypeOfJointButtonGroup);
            app.is_prismatic.Text = 'Prismatic';
            app.is_prismatic.Value = true;
            app.is_revolute = uiradiobutton(app.TypeOfJointButtonGroup);
            app.is_revolute.Text = 'Revolute';
            app.ControlVariablesPanel = uipanel(app.UIFigure);
            app.ControlVariablesPanel.Title = 'Control Variables';
            app.qEditFieldLabel = uilabel(app.ControlVariablesPanel);
            app.qEditFieldLabel.Text = 'q';
            app.q0 = uieditfield(app.ControlVariablesPanel, 'text');
            app.InitialValuesLabel = uilabel(app.ControlVariablesPanel);
            app.InitialValuesLabel.Text = 'Initial Values';
            app.qdEditFieldLabel = uilabel(app.ControlVariablesPanel);
            app.qdEditFieldLabel.Text = 'qd';
            app.qd0 = uieditfield(app.ControlVariablesPanel, 'text');
            app.Entervaluesforqandqdasvectorsq1q2q3andforrevoluteLabel = uilabel(app.ControlVariablesPanel);
            app.Entervaluesforqandqdasvectorsq1q2q3andforrevoluteLabel.Text = {'**Enter values for q and qd as vectors: [q1;q2;q3] '; 'For revolute joints specify q in RADIANs. and qd rad/sec '};
            app.OpSpace = uibuttongroup(app.ControlVariablesPanel);
            app.OpSpace.Title = 'Operational Space dimension';
            app.isTwoD = uiradiobutton(app.OpSpace);
            app.isTwoD.Text = '2D';
            app.isTwoD.Value = true;
            app.isThreeD = uiradiobutton(app.OpSpace);
            app.isThreeD.Text = '3D';
            app.PDControlButton = uibutton(app.ControlVariablesPanel, 'push');
            app.PDControlButton.ButtonPushedFcn = createCallbackFcn(app, @PDControlButtonPushed, true);
            app.PDControlButton.Text = 'PD Control';
            app.InvDynamicsControlButton = uibutton(app.ControlVariablesPanel, 'push');
            app.InvDynamicsControlButton.Text = 'Inv Dynamics Control';
            app.DoneButton = uibutton(app.ControlVariablesPanel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.SelectyourcontrolmethodLabel = uilabel(app.ControlVariablesPanel);
            app.SelectyourcontrolmethodLabel.Text = 'Select your control method';
            app.EulerAnglesZYZformatLabel = uilabel(app.ControlVariablesPanel);
            app.EulerAnglesZYZformatLabel.Text = {'Euler Angles'; '(ZYZ format)'};
            app.EulerAngles = uieditfield(app.ControlVariablesPanel, 'text');
            app.DesiredPoseLabel = uilabel(app.ControlVariablesPanel);
            app.DesiredPoseLabel.Text = 'Desired Pose';
            app.DesiredPose = uieditfield(app.ControlVariablesPanel, 'text');
            app.xd_xe = uiaxes(app.UIFigure);
            title(app.xd_xe, 'Desired v/s End-effector')
            xlabel(app.xd_xe, 'X_e')
            ylabel(app.xd_xe, 'X_d')
            zlabel(app.xd_xe, 'Z')
            app.lin_for = uiaxes(app.UIFigure);
            title(app.lin_for, 'Linear force ')
            ylabel(app.lin_for, 'force vector')
            zlabel(app.lin_for, 'Z')
            app.ang_for = uiaxes(app.UIFigure);
            title(app.ang_for, 'Angular force ')
            xlabel(app.ang_for, 'X')
            ylabel(app.ang_for, 'Angular force vector')
            zlabel(app.ang_for, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end